classdef MATLAB_task3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UploadImageButton              matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        Task1Tab                       matlab.ui.container.Tab
        ColorSpaceGroup                matlab.ui.container.ButtonGroup
        YCbCrButton                    matlab.ui.control.RadioButton
        LabButton                      matlab.ui.control.RadioButton
        HSIButton                      matlab.ui.control.RadioButton
        RGBButton                      matlab.ui.control.RadioButton
        HistogramEqualizationButton    matlab.ui.control.Button
        BrightnessSlider               matlab.ui.control.Slider
        BrightnessSliderLabel          matlab.ui.control.Label
        SpatialDomainFilteringPanel    matlab.ui.container.Panel
        ApplyFilterButton              matlab.ui.control.Button
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        FilterTypeDropDown             matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        FrequencyDomainFilteringPanel  matlab.ui.container.Panel
        ApplyFrequencyButton           matlab.ui.control.Button
        FrequencyMethodDropDown        matlab.ui.control.DropDown
        FrequencyMethodLabel           matlab.ui.control.Label
        FrequencyTypeDropDown          matlab.ui.control.DropDown
        FrequencyTypeLabel             matlab.ui.control.Label
        Task2Tab                       matlab.ui.container.Tab
        EdgeDetectionPanel             matlab.ui.container.Panel
        FindEdgesButton                matlab.ui.control.Button
        SigmaSlider                    matlab.ui.control.Slider
        SigmaSliderLabel               matlab.ui.control.Label
        DirectionDropDown              matlab.ui.control.DropDown
        DirectionDropDownLabel         matlab.ui.control.Label
        ThresholdSpinner               matlab.ui.control.Spinner
        ThresholdSpinnerLabel          matlab.ui.control.Label
        MethodDropDown_2               matlab.ui.control.DropDown
        MethodDropDown_2Label          matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        LMFilterBanksTab               matlab.ui.container.Tab
        GaussianFiltersDropDown        matlab.ui.control.DropDown
        GaussianFiltersDropDownLabel   matlab.ui.control.Label
        LOGFiltersDropDown             matlab.ui.control.DropDown
        LOGFiltersDropDownLabel        matlab.ui.control.Label
        ApplyGaussianFilterButton      matlab.ui.control.Button
        ApplyLOGFilterButton           matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        ApplyButton                    matlab.ui.control.Button
        ScaleSpinner                   matlab.ui.control.Spinner
        ScaleSpinnerLabel              matlab.ui.control.Label
        GaussianDerivativeDropDown     matlab.ui.control.DropDown
        GaussianDerivativeDropDownLabel  matlab.ui.control.Label
        OrientationKnob                matlab.ui.control.DiscreteKnob
        OrientationKnobLabel           matlab.ui.control.Label
        SchmidFilterBankTab            matlab.ui.container.Tab
        ApplyFilterButton_2            matlab.ui.control.Button
        SchmidPairsDropDown            matlab.ui.control.DropDown
        SchmidPairsDropDownLabel       matlab.ui.control.Label
        TemplateMatchingPanel          matlab.ui.container.Panel
        ShowCorrelationButton          matlab.ui.control.Button
        RunMatchingButton              matlab.ui.control.Button
        SelectTemplateButton           matlab.ui.control.Button
        ImagePyramidPanel              matlab.ui.container.Panel
        ApplyPyramidButton             matlab.ui.control.Button
        ImagePyramidTypeDropDown       matlab.ui.control.DropDown
        ImagePyramidTypeDropDownLabel  matlab.ui.control.Label
        ImagePyramidLevelSpinner       matlab.ui.control.Spinner
        ImagePyramidLevelSpinnerLabel  matlab.ui.control.Label
        CornerDetectionTab             matlab.ui.container.Tab
        CornerDetectionPanel           matlab.ui.container.Panel
        FindCornersButton              matlab.ui.control.Button
        FilterSizeSpinner              matlab.ui.control.Spinner
        FilterSizeSpinnerLabel         matlab.ui.control.Label
        NumberofCornersSpinner         matlab.ui.control.Spinner
        NoofCornersLabel               matlab.ui.control.Label
        QualitySlider                  matlab.ui.control.Slider
        QualitySliderLabel             matlab.ui.control.Label
        Task3Tab                       matlab.ui.container.Tab
        RANSACPanel                    matlab.ui.container.Panel
        ApplyRANSACCircleButton        matlab.ui.control.Button
        CircleRmaxSpinner              matlab.ui.control.Spinner
        CircleRmaxSpinnerLabel         matlab.ui.control.Label
        CircleRminSpinner              matlab.ui.control.Spinner
        CircleRminSpinnerLabel         matlab.ui.control.Label
        ApplyRANSACLineButton          matlab.ui.control.Button
        RANSACIteratorSpinner          matlab.ui.control.Spinner
        RANSACIteratorSpinnerLabel     matlab.ui.control.Label
        RANSACThresholdSpinner         matlab.ui.control.Spinner
        RANSACThresholdSpinnerLabel    matlab.ui.control.Label
        HoughTransformPanel            matlab.ui.container.Panel
        ApplyHoughCirclesButton        matlab.ui.control.Button
        SensitivitySpinner             matlab.ui.control.Spinner
        SensitivitySpinnerLabel        matlab.ui.control.Label
        MaxRadiusSpinner               matlab.ui.control.Spinner
        MaxRadiusSpinnerLabel          matlab.ui.control.Label
        MinRadiusSpinner               matlab.ui.control.Spinner
        MinRadiusSpinnerLabel          matlab.ui.control.Label
        ApplyHoughLinesButton          matlab.ui.control.Button
        MaxLinePeaksSpinner            matlab.ui.control.Spinner
        MaxLinePeaksSpinnerLabel       matlab.ui.control.Label
        EdgeMethodDropDown             matlab.ui.control.DropDown
        EdgeMethodDropDownLabel        matlab.ui.control.Label
        HOGPanel                       matlab.ui.container.Panel
        ApplyHOGButton                 matlab.ui.control.Button
        BlockOverlapSpinner            matlab.ui.control.Spinner
        BlockOverlapLabel              matlab.ui.control.Label
        SignedOrientationCheckBox      matlab.ui.control.CheckBox
        NoofBinsSpinner                matlab.ui.control.Spinner
        NoofBinsSpinnerLabel           matlab.ui.control.Label
        BlockSizeSpinner               matlab.ui.control.Spinner
        BlockSizeSpinnerLabel          matlab.ui.control.Label
        CellSizeSpinner                matlab.ui.control.Spinner
        CellSizeSpinnerLabel           matlab.ui.control.Label
        DoGLoGPanel                    matlab.ui.container.Panel
        ApplyLoGButton                 matlab.ui.control.Button
        SigmaLoGValueSpinner           matlab.ui.control.Spinner
        SigmaLoGValueLabel             matlab.ui.control.Label
        ApplyDOGButton                 matlab.ui.control.StateButton
        SigmaSecondValueSpinner        matlab.ui.control.Spinner
        SigmaSecondValueSpinnerLabel   matlab.ui.control.Label
        SigmaFirstValueSpinner         matlab.ui.control.Spinner
        SigmaFirstValueSpinnerLabel    matlab.ui.control.Label
        StereoVisionTab                matlab.ui.container.Tab
        StereoVisionPanel              matlab.ui.container.Panel
        Compute3DButton                matlab.ui.control.Button
        FocalLengthEditField           matlab.ui.control.NumericEditField
        FocalLengthEditFieldLabel      matlab.ui.control.Label
        ShowEpipolarLinesButton        matlab.ui.control.Button
        MatchLimitSpinner              matlab.ui.control.Spinner
        MatchLimitSpinnerLabel         matlab.ui.control.Label
        UploadRightImageButton         matlab.ui.control.Button
        UploadLeftImageButton          matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        ResetImageButton               matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CurrentImage % Description
        SavedImage
        CurrentTemplate %for template matching
        TemplateMask
        hImage
        CurrentTemplateMask
        Keypoints %for HOG around points
        LeftImage
        RightImage
        WorldPoints    
    end
    
    methods (Access = private)
        
        function g = gaussianKernel(~, sigma, size)
            if nargin < 3
                size = round(6*sigma);
                if mod(size,2)==0, size=size+1; end
            end
            x = -(size-1)/2 : (size-1)/2;
            y = x';
            g = exp(-(x.^2 + y.^2) / (2*sigma^2));
            g = g / sum(g(:));
        end

        function gx = gaussianFirstDerivative(app, sigma, orientation)
            orientation = str2double(string(orientation));

            size = round(6*sigma);
            if mod(size,2)==0, size = size + 1; end

            [X, Y] = meshgrid(-(size-1)/2:(size-1)/2);

            % Convert angle to radians
            theta = deg2rad(orientation);

            % Rotate coordinates
            Xr = X*cos(theta) + Y*sin(theta);

            gx = -Xr .* exp(-(Xr.^2 + Y.^2) / (2*sigma^2));

            gx = gx / sum(abs(gx(:)));
        end

        function g2 = gaussianSecondDerivative(~, sigma, orientation)
            orientation = str2double(string(orientation));

            size = round(6*sigma);
            if mod(size,2)==0, size = size + 1; end

            [X, Y] = meshgrid(-(size-1)/2:(size-1)/2);

            theta = deg2rad(orientation);
            Xr = X*cos(theta) + Y*sin(theta);

            g2 = (Xr.^2 / sigma^2 - 1) .* exp(-(Xr.^2 + Y.^2) / (2*sigma^2));

            g2 = g2 / sum(abs(g2(:)));
        end

        function logf = laplacianOfGaussian(app, sigma)
            size = round(6*sigma); if mod(size,2)==0, size=size+1; end
            [X, Y] = meshgrid(-(size-1)/2:(size-1)/2);

            r2 = X.^2 + Y.^2;
            logf = (r2 - sigma^2) .* exp(-r2 / (2*sigma^2));
            logf = logf / sum(abs(logf(:)));
        end

        function gf = gaussianBlur(app, sigma)
            gf = app.gaussianKernel(sigma);
        end

        function F = schmidFilter(app, sigma, r)
            % filter size (3σ on each side)
            size = round(6 * sigma);
            if mod(size,2)==0, size = size + 1; end

            [X, Y] = meshgrid(-(size-1)/2:(size-1)/2);

            R = sqrt(X.^2 + Y.^2);

            % Schmid filter formula
            F = cos(pi * r * R / sigma) .* exp(-(R.^2) / (2 * sigma^2));

            % Normalize
            F = F - mean(F(:));   % zero-mean
            F = F / sum(abs(F(:)));
        end

        function pairs = schmidPairs(app)
            pairs = [
                2 1;
                4 1;
                4 2;
                6 1;
                6 2;
                6 3;
                8 1;
                8 2;
                8 3;
                10 1;
                10 2;
                10 3;
                10 4
                ];
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            img = imread('C:\Users\leenm\Downloads\Test_images_task1\Test_images_task1\autumn.tif');

            value = app.BrightnessSlider.Value;
            brightness = img + value;
            imshow(brightness, 'Parent', app.UIAxes);
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            img = imread('C:\Users\leenm\Downloads\Test_images_task1\Test_images_task1\autumn.tif');

            if size(img,3) == 3
                img = rgb2gray(img);
            end
            hist = histeq(img);
            imshow(hist, 'Parent', app.UIAxes);
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            switch value
                case 'Smoothing'
                    app.MethodDropDown.Items = {'Box', 'Weighted Average', 'Median'};
                case 'Sharpening'
                    app.MethodDropDown.Items = {'Laplacian (1st Derivative)', ...
                        'Laplacian (2nd Derivative)', ...
                        'Boosting', ...
                        'Sobel (Horizontal)', ...
                        'Sobel (Vertical)', ...
                        'Prewitt'};
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            img = imread('C:\Users\leenm\Downloads\Test_images_task1\Test_images_task1\autumn.tif');

            if ndims(img) == 3
                img = rgb2gray(img);
            end
            method = app.MethodDropDown.Value;

            switch method
            %% ----- Smoothing Filters -----
                case 'Box'
                    h = fspecial('average', [3 3]);
                    out = imfilter(img, h);
                case 'Weighted Average'
                    h = fspecial('gaussian', [3 3], 1);
                    out = imfilter(img, h);
                case 'Median'
                    out = medfilt2(img, [3 3]);

            %% ----- Sharpening Filters -----
                case 'Laplacian (1st Derivative)'
                    h = fspecial('laplacian', 0.2);
                    out = imfilter(img, h);
                case 'Laplacian (2nd Derivative)'
                    h = fspecial('laplacian', 0);
                    out = imfilter(img, h);
                case 'Boosting'
                    h = fspecial('average', [3 3]);
                    smooth = imfilter(img, h);
                    out = imadd(img, imsubtract(img, smooth));
                case 'Sobel (Horizontal)'
                    h = fspecial('sobel');
                    out = imfilter(img, h);
                case 'Sobel (Vertical)'
                    h = fspecial('sobel')';
                    out = imfilter(img, h);
                case 'Prewitt'
                    h = fspecial('prewitt');
                    out = imfilter(img, h);
            end

            imshow(out, 'Parent', app.UIAxes);
        end

        % Button pushed function: ApplyFrequencyButton
        function ApplyFrequencyButtonPushed(app, event)
            img = imread('C:\Users\leenm\Downloads\Test_images_task1\Test_images_task1\autumn.tif');

            if size(img,3) == 3
                img = rgb2gray(img);
            end
            img = im2double(img);

            % Read filter selections
            ftype = app.FrequencyTypeDropDown.Value;      % Low-pass or High-pass
            method = app.FrequencyMethodDropDown.Value;   % Ideal, Butterworth, Gaussian
            D0 = 30;   % Cutoff frequency (you can make this a slider later)

            % Fourier transform
            F = fftshift(fft2(img));
            [M, N] = size(F);
            [U, V] = meshgrid(1:N, 1:M);
            D = sqrt((U - N/2).^2 + (V - M/2).^2);

            % Initialize filter
            H = zeros(M, N);

            switch method
                case 'Ideal'
                    H = double(D <= D0);
                    if strcmp(ftype, 'High-pass')
                        H = 1 - H;
                    end

                case 'Butterworth'
                    n = 2; % filter order
                    if strcmp(ftype, 'Low-pass')
                        H = 1 ./ (1 + (D ./ D0).^(2*n));
                    else
                        H = 1 ./ (1 + (D0 ./ D).^(2*n));
                    end

                case 'Gaussian'
                    if strcmp(ftype, 'Low-pass')
                        H = exp(-(D.^2) / (2*(D0^2)));
                    else
                        H = 1 - exp(-(D.^2) / (2*(D0^2)));
                    end
            end

            G = H .* F;
            g = real(ifft2(ifftshift(G)));

            imshow(g, [], 'Parent', app.UIAxes);
        end

        % Selection changed function: ColorSpaceGroup
        function ColorSpaceGroupSelectionChanged(app, event)
            img = imread('C:\Users\leenm\Downloads\Test_images_task1\Test_images_task1\autumn.tif');

            % Ensure 3-channel RGB image
            if size(img,3) ~= 3
                img = repmat(img, [1 1 3]);
            end

            % Determine which radio button was selected
            selectedButton = app.ColorSpaceGroup.SelectedObject;
            space = selectedButton.Text;  % Text property holds the label
            converted = [];

            switch space
                case 'RGB'
                    converted = img;
        
                case 'HSI'
                    converted = rgb2hsv(img);
        
                case 'Lab'
                    converted = rgb2lab(img);
        
                case 'YCbCr'
                    converted = rgb2ycbcr(img);
            end

            imshow(converted, 'Parent', app.UIAxes);
            title(app.UIAxes, ['Converted to ' space]);
        end

        % Button pushed function: ResetImageButton
        function ResetImageButtonPushed(app, event)
            if isempty(app.SavedImage)
                uialert(app.UIFigure, 'No saved image to reset.', 'Error');
                return;
            end
            app.CurrentImage = app.SavedImage;
            imshow(app.SavedImage, 'Parent', app.UIAxes2);
            imshow(app.SavedImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif)'}, ...
                             'Select an Image');

            if isequal(file, 0)
                return;
            end
        
            fullPath = fullfile(path, file);
            img = imread(fullPath);
        
            % Store the uploaded image in app property (for later use)
            app.CurrentImage = img;
            app.SavedImage = img;
        
            % Display it in the UIAxes
            imshow(img, 'Parent', app.UIAxes);
            title(app.UIAxes, sprintf('Loaded Image: %s', file));
        end

        % Button pushed function: ApplyPyramidButton
        function ApplyPyramidButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, 'Please upload an image first.', 'No Image');
                return;
            end

            % Get number of levels (1–3 from spinner)
            img = im2double(app.CurrentImage);

            level = app.ImagePyramidLevelSpinner.Value;      % 1, 2, or 3
            type  = app.ImagePyramidTypeDropDown.Value;      % 'expand' or 'reduce'

            % Apply pyramid operations repeatedly depending on the level
            for i = 1:level
                img = impyramid(img, type);
            end

            % Display result
            imshow(img, 'Parent', app.UIAxes2);

            axis(app.UIAxes2, 'image');
            app.UIAxes2.XLimMode = 'auto';
            app.UIAxes2.YLimMode = 'auto';

            app.SavedImage = img;
        end

        % Button pushed function: SelectTemplateButton
        function SelectTemplateButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please upload an image first.", "No Image");
                return;
            end

            app.hImage = imshow(app.CurrentImage, 'Parent', app.UIAxes);
            I = app.hImage.CData;

            % Prompt user
            uialert(app.UIFigure, "Click to draw a polygon around the template, then double-click to finish.", "Select Template");

            % Let user draw polygon
            mask = roipoly(I);

            if isempty(mask)
                uialert(app.UIFigure, "No region was selected.", "Error");
                return;
            end

            [rows, cols] = find(mask);
            rowMin = min(rows); rowMax = max(rows);
            colMin = min(cols); colMax = max(cols);

            % Crop template and mask
            template = I(rowMin:rowMax, colMin:colMax, :);
            croppedMask = mask(rowMin:rowMax, colMin:colMax);

            % Save template and mask (now ready for matching)
            app.CurrentTemplate = template;
            app.CurrentTemplateMask = croppedMask;

            uialert(app.UIFigure, ...
                "Template selected and saved successfully! You may now run matching.", ...
                "Template Stored");
        end

        % Button pushed function: RunMatchingButton
        function RunMatchingButtonPushed(app, event)
            if isempty(app.CurrentTemplate)
                uialert(app.UIFigure, "Please confirm the template first.", "No Template");
                return;
            end

            img = app.CurrentImage;
            templ = app.CurrentTemplate;
            mask = app.CurrentTemplateMask;

            % Convert to grayscale
            if size(img,3)==3, imgGray = rgb2gray(img); else, imgGray = img; end
            if size(templ,3)==3, templGray = rgb2gray(templ); else, templGray = templ; end

            % Apply mask: remove background pixels
            templGray(~mask) = 0;

            % Cross-correlation
            corrImage = normxcorr2(templGray, imgGray);

            % Peak location
            [~, maxIdx] = max(corrImage(:));
            [ypeak, xpeak] = ind2sub(size(corrImage), maxIdx);

            % Compute top-left corner
            yoff = ypeak - size(templGray,1);
            xoff = xpeak - size(templGray,2);

            % Display match
            imshow(img, 'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'on');
            rectangle(app.UIAxes2, ...
                'Position', [xoff yoff size(templGray,2) size(templGray,1)], ...
                'EdgeColor','g', 'LineWidth',2);
            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: ShowCorrelationButton
        function ShowCorrelationButtonPushed(app, event)
            if isempty(app.CurrentTemplate)
                uialert(app.UIFigure, "Please confirm the template first.", "No Template");
                return;
            end

            img = app.CurrentImage;
            templ = app.CurrentTemplate;
            mask = app.CurrentTemplateMask;

            if size(img,3)==3, imgGray = rgb2gray(img); else, imgGray = img; end
            if size(templ,3)==3, templGray = rgb2gray(templ); else, templGray = templ; end

            templGray(~mask) = 0;

            corrImage = normxcorr2(templGray, imgGray);

            figure('Name','Correlation Surface','NumberTitle','off');
            surf(corrImage)
            shading interp
            colormap('jet')
            colorbar
            title('3D Normalized Cross-Correlation')
            xlabel('X')
            ylabel('Y')
            zlabel('Correlation Value')
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            scale = app.ScaleSpinner.Value;   % 1,2,3
            sigma = scale * 2;                % You may adjust

            orientation = app.OrientationKnob.Value;
            derivative = app.GaussianDerivativeDropDown.Value;

            % Load image
            I = app.CurrentImage;
            if size(I,3)==3, I = rgb2gray(I); end

            % Select filter type
            switch derivative
                case 'First Derivative'
                    F = app.gaussianFirstDerivative(sigma, orientation);
                case 'Second Derivative'
                    F = app.gaussianSecondDerivative(sigma, orientation);
            end

            % Apply filter
            result = imfilter(double(I), F, 'replicate');

            % Display
            imshow(result, [], 'Parent', app.UIAxes2);
        end

        % Button pushed function: ApplyLOGFilterButton
        function ApplyLOGFilterButtonPushed(app, event)
            sigma = str2double(app.LOGFiltersDropDown.Value);

            I = app.CurrentImage;
            if size(I,3)==3, I = rgb2gray(I); end

            F = app.laplacianOfGaussian(sigma);

            result = imfilter(double(I), F, 'replicate');
            imshow(result, [], 'Parent', app.UIAxes2);
        end

        % Button pushed function: ApplyGaussianFilterButton
        function ApplyGaussianFilterButtonPushed(app, event)
            sigma = str2double(app.GaussianFiltersDropDown.Value);

            I = app.CurrentImage;
            if size(I,3)==3, I = rgb2gray(I); end

            F = app.gaussianBlur(sigma);
            result = imfilter(double(I), F, 'replicate');

            imshow(result, [], 'Parent', app.UIAxes2);
        end

        % Button pushed function: ApplyFilterButton_2
        function ApplyFilterButton_2Pushed(app, event)
            idx = str2double(app.SchmidPairsDropDown.Value);

            % List of all sigma,r pairs
            pairs = app.schmidPairs();
            sigma = pairs(idx,1);
            r     = pairs(idx,2);

            % Load image
            I = app.CurrentImage;
            if size(I,3)==3, I = rgb2gray(I); end

            % Build filter
            F = app.schmidFilter(sigma, r);

            % Apply filter
            result = imfilter(double(I), F, 'replicate');

            % Show output
            imshow(result, [], 'Parent', app.UIAxes2);
        end

        % Button pushed function: FindEdgesButton
        function FindEdgesButtonPushed(app, event)
            I = app.CurrentImage;
            if size(I,3)==3
                I = rgb2gray(I);
            end

            % Read UI values
            method = lower(app.MethodDropDown_2.Value);
            direction = lower(app.DirectionDropDown.Value);
            threshold = app.ThresholdSpinner.Value;
            sigma = app.SigmaSlider.Value;

            % Prepare inputs for edge()
            % Threshold must be [] if threshold = 0
            if threshold == 0
                thresholdInput = [];
            else
                thresholdInput = threshold;
            end

            % Determine which case to use
            switch method

                % --- METHODS WITH DIRECTION ---
                case {'sobel','prewitt','roberts'}
                    switch direction
                        case 'horizontal'
                            dir = 'horizontal';
                        case 'vertical'
                            dir = 'vertical';
                        otherwise
                            dir = 'both';
                    end

                    BW = edge(I, method, thresholdInput, dir);

                    % --- METHODS WITH SIGMA ONLY ---
                case {'log','zerocross', 'approxcanny'}
                    BW = edge(I, method, thresholdInput);

                    % --- CANNY VARIANTS (with sigma & threshold) ---
                case {'canny'}
                    BW = edge(I, method, thresholdInput, sigma);

                otherwise
                    uialert(app.UIFigure, "Unknown method selected.", "Error");
                    return;
            end

            % Display results
            imshow(BW, 'Parent', app.UIAxes2);
        end

        % Button pushed function: FindCornersButton
        function FindCornersButtonPushed(app, event)
            I = app.CurrentImage;
            if isempty(I)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            % Convert to grayscale
            if size(I,3)==3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end

            % Read user parameters
            quality = app.QualitySlider.Value;               % 0–1
            numCorners = app.NumberofCornersSpinner.Value;        % 5–inf
            filterSize = app.FilterSizeSpinner.Value;        % 3–21

            % Ensure valid filter size (must be odd)
            if mod(filterSize,2) == 0
                filterSize = filterSize + 1;
            end

            % Perform Harris detection
            corners = detectHarrisFeatures(Igray, ...
                'MinQuality', quality, ...
                'FilterSize', filterSize);

            % Select strongest N corners
            strongest = corners.selectStrongest(numCorners);

            % Display image
            imshow(I, 'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'on');

            % Plot corners
            plot(app.UIAxes2, strongest.Location(:,1), strongest.Location(:,2), ...
                'ro', 'MarkerSize', 5, 'LineWidth', 1.5);

            hold(app.UIAxes2, 'off');
        end

        % Value changed function: ApplyDOGButton
        function ApplyDOGButtonValueChanged(app, event)
                if isempty(app.CurrentImage)
                    uialert(app.UIFigure, "Please load an image first.", "Error");
                    return;
                end

                % Read input image
                I = app.CurrentImage;
                if size(I,3)==3
                    Igray = rgb2gray(I);
                else
                    Igray = I;
                end

                % Read sigma values
                sigma1 = app.SigmaFirstValueSpinner.Value;
                sigma2 = app.SigmaSecondValueSpinner.Value;

                % Apply Gaussian smoothing
                G1 = imgaussfilt(Igray, sigma1);
                G2 = imgaussfilt(Igray, sigma2);

                % Difference of Gaussians
                DoG = G1 - G2;

                % Display output
                imshow(DoG, [], 'Parent', app.UIAxes2);
        end

        % Button pushed function: ApplyLoGButton
        function ApplyLoGButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            % Read image
            I = app.CurrentImage;
            if size(I,3)==3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end

            % Read sigma
            sigma = app.SigmaLoGValueSpinner.Value;

            % Determine kernel size (typically 6*sigma)
            hsize = round(6 * sigma);
            if mod(hsize,2)==0, hsize = hsize + 1; end

            % Create LoG filter kernel
            LoG = fspecial('log', hsize, sigma);

            % Apply LoG filter
            result = imfilter(double(Igray), LoG, 'replicate');

            % Display result
            imshow(result, [], 'Parent', app.UIAxes2);
        end

        % Button pushed function: ApplyHOGButton
        function ApplyHOGButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            % Load grayscale version
            I = app.CurrentImage;
            if size(I,3) == 3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end

            % Read parameters
            cellSizeVal = app.CellSizeSpinner.Value;     % pixels
            blockCells = app.BlockSizeSpinner.Value;     % number of cells per block
            overlapCells = app.BlockOverlapSpinner.Value; % number of cells overlapping
            numBins = app.NoofBinsSpinner.Value;
            signed = app.SignedOrientationCheckBox.Value;  % true/false

            % Convert to HOG parameters
            CellSize = [cellSizeVal cellSizeVal];
            BlockSize = [blockCells blockCells] .* cellSizeVal;   % convert cells → pixels
            BlockOverlap = [overlapCells overlapCells] .* cellSizeVal;

            % Compute HOG features and visualization
            [features, visualization] = extractHOGFeatures(Igray, ...
                'CellSize', CellSize, ...
                'BlockSize', BlockSize, ...
                'BlockOverlap', BlockOverlap, ...
                'NumBins', numBins, ...
                'UseSignedOrientation', signed);

            tempFig = figure('Visible', 'off');
            imshow(Igray);
            hold on;
            plot(visualization);
            frame = getframe(gca);  % Capture the image with plotted features
            imgOut = frame.cdata;
            close(tempFig); 
            
            imshow(imgOut, 'Parent', app.UIAxes2);
        end

        % Callback function
        function DetectKeypointsButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            I = app.CurrentImage;
            if size(I,3)==3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end

            method = app.KeypointDetectorDropDown.Value;
            numPts = app.StrongPointsSpinner.Value;

            switch method
                case 'FAST'
                    pts = detectFASTFeatures(Igray);
                case 'Harris'
                    pts = detectHarrisFeatures(Igray);
                case 'MinEigen'
                    pts = detectMinEigenFeatures(Igray);
            end

            strongest = pts.selectStrongest(numPts);

            % Store them for next step
            app.Keypoints = strongest;

            % Show points in UIAxes (simple scatter)
            imshow(Igray, 'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'on');
            plot(app.UIAxes2, strongest.Location(:,1), strongest.Location(:,2), 'go');
            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: ApplyHoughLinesButton
        function ApplyHoughLinesButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            % Convert to grayscale
            I = app.CurrentImage;
            if size(I,3)==3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end

            % Read user options
            method = lower(app.EdgeMethodDropDown.Value);
            maxPeaks = app.MaxLinePeaksSpinner.Value;

            % Step 1: Edge detection
            BW = edge(Igray, method);

            % Step 2: Hough transform
            [H, theta, rho] = hough(BW, 'Theta', -90:0.5:89.5);  % limited theta range

            % Step 3: Find Hough peaks
            peaks = houghpeaks(H, maxPeaks);

            % Step 4: Extract lines
            lines = houghlines(BW, theta, rho, peaks);

            % Visualization 1: Green lines on UIAxes
            imshow(I, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');

            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
                plot(app.UIAxes, xy(:,1), xy(:,2), 'g-', 'LineWidth', 2);
            end

            hold(app.UIAxes, 'off');

            % Visualization 2: Hough transform (UIAxes2)
            imshow(H, [], 'Parent', app.UIAxes2);
            colormap(app.UIAxes2, hot);
            axis(app.UIAxes2, 'on');
            title(app.UIAxes2, 'Hough Transform (Limited \theta Range)');
        end

        % Button pushed function: ApplyHoughCirclesButton
        function ApplyHoughCirclesButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            % Convert to grayscale
            I = app.CurrentImage;
            if size(I,3)==3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end

            % Read user values
            minR = app.MinRadiusSpinner.Value;
            maxR = app.MaxRadiusSpinner.Value;
            sens = app.SensitivitySpinner.Value;

            % Detect circles
            [centers, radii, metrics] = imfindcircles(Igray, [minR maxR], ...
                'Sensitivity', sens, ...
                'EdgeThreshold', 0.1);

            % Visualization: show image with detected circles
            imshow(I, 'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'on');

            if ~isempty(centers)
                viscircles(app.UIAxes2, centers, radii, 'Color', 'g');
            else
                title(app.UIAxes2, 'No circles detected');
            end

            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: ApplyRANSACLineButton
        function ApplyRANSACLineButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            % Convert to grayscale
            I = app.CurrentImage;
            if size(I,3)==3, Igray = rgb2gray(I); else, Igray = I; end

            % Edge detection (Canny recommended)
            BW = edge(Igray, 'canny');

            % Extract edge coordinates
            [y, x] = find(BW);
            points = [x y];

            % RANSAC parameters
            maxIter = app.RANSACIteratorSpinner.Value;
            thresh = app.RANSACThresholdSpinner.Value;

            bestInliers = [];
            bestModel = [];

            % --- RANSAC LOOP ---
            for iter = 1:maxIter
                % Pick 2 random points
                idx = randperm(size(points,1), 2);
                p1 = points(idx(1), :);
                p2 = points(idx(2), :);

                if isequal(p1, p2)
                    continue;
                end

                % Line model: ax + by + c = 0
                a = p2(2) - p1(2);
                b = p1(1) - p2(1);
                c = p2(1)*p1(2) - p1(1)*p2(2);

                % Distance of all points from the line
                dist = abs(a*points(:,1) + b*points(:,2) + c) / sqrt(a^2 + b^2);

                % Inliers
                inliers = find(dist < thresh);

                if length(inliers) > length(bestInliers)
                    bestInliers = inliers;
                    bestModel = [a b c];
                end
            end

    % Visualize best RANSAC line
    imshow(I, 'Parent', app.UIAxes);
    hold(app.UIAxes, 'on');

    if isempty(bestInliers)
        title(app.UIAxes, 'No RANSAC line found');
    else
        % Points for plotting
        a = bestModel(1); b = bestModel(2); c = bestModel(3);

        xVals = 1:size(Igray,2);
        yVals = -(a*xVals + c) / b;

        plot(app.UIAxes, xVals, yVals, 'g-', 'LineWidth', 2);
        title(app.UIAxes, 'RANSAC Line Detection');
    end

    hold(app.UIAxes, 'off');
        end

        % Button pushed function: ApplyRANSACCircleButton
        function ApplyRANSACCircleButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, "Please load an image first.", "Error");
                return;
            end

            I = app.CurrentImage;
            if size(I,3)==3, Igray = rgb2gray(I); else, Igray = I; end

            BW = edge(Igray, 'canny');
            [y, x] = find(BW);
            points = [x y];

            Rmin = app.CircleRminSpinner.Value;
            Rmax = app.CircleRmaxSpinner.Value;

            maxIter = 2000;
            bestInliers = [];
            bestCircle = [];

            for iter = 1:maxIter
                % Pick 3 random points
                idx = randperm(size(points,1), 3);
                p1 = points(idx(1), :);
                p2 = points(idx(2), :);
                p3 = points(idx(3), :);

                % Compute circle from 3 points
                A = [p1(1) p1(2) 1;
                    p2(1) p2(2) 1;
                    p3(1) p3(2) 1];

                if det(A) == 0
                    continue;
                end

                % Solve for center (xc, yc)
                B = -[p1(1)^2 + p1(2)^2;
                    p2(1)^2 + p2(2)^2;
                    p3(1)^2 + p3(2)^2];

                params = A \ B;
                xc = -params(1)/2;
                yc = -params(2)/2;
                r = sqrt((params(1)^2 + params(2)^2) / 4 - params(3));

                % Radius limits
                if r < Rmin || r > Rmax
                    continue;
                end

                % Compute distances of all points from circle
                dist = abs(sqrt((points(:,1)-xc).^2 + (points(:,2)-yc).^2) - r);

                % Inliers threshold
                inliers = find(dist < 2); % 2 pixel tolerance

                if length(inliers) > length(bestInliers)
                    bestInliers = inliers;
                    bestCircle = [xc yc r];
                end
            end

            % Visualize
            imshow(I, 'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'on');

            if isempty(bestCircle)
                title(app.UIAxes2, 'No RANSAC circle found');
            else
                viscircles(app.UIAxes2, bestCircle(1:2), bestCircle(3), 'Color', 'g', 'LineWidth', 2);
                title(app.UIAxes2, 'RANSAC Circle Detection');
            end

            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: UploadLeftImageButton
        function UploadLeftImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'});
            if isequal(file,0), return; end

            I = imread(fullfile(path, file));
            app.LeftImage = I;

            imshow(I, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Left Image');
        end

        % Button pushed function: UploadRightImageButton
        function UploadRightImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'});
            if isequal(file,0), return; end

            I = imread(fullfile(path, file));
            app.RightImage = I;

            imshow(I, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Right Image');
        end

        % Button pushed function: ShowEpipolarLinesButton
        function ShowEpipolarLinesButtonPushed(app, event)
            if isempty(app.LeftImage) || isempty(app.RightImage)
                uialert(app.UIFigure, "Please upload both images first.", "Error");
                return;
            end

            I1 = app.LeftImage;
            I2 = app.RightImage;

            if size(I1,3)==3, I1g = rgb2gray(I1); else, I1g = I1; end
            if size(I2,3)==3, I2g = rgb2gray(I2); else, I2g = I2; end

            %% --- Feature detection & matching (automatic) ---
            pts1 = detectSURFFeatures(I1g);
            pts2 = detectSURFFeatures(I2g);

            [f1, v1] = extractFeatures(I1g, pts1);
            [f2, v2] = extractFeatures(I2g, pts2);

            indexPairs = matchFeatures(f1, f2, 'Unique', true);

            matchedPoints1 = v1(indexPairs(:,1)).Location;
            matchedPoints2 = v2(indexPairs(:,2)).Location;

            %% --- Apply match limit (minimum 8) ---
            limit = max(app.MatchLimitSpinner.Value, 8);
            limit = min(limit, size(matchedPoints1,1));

            matchedPoints1 = matchedPoints1(1:limit,:);
            matchedPoints2 = matchedPoints2(1:limit,:);

            %% --- Estimate Fundamental Matrix (LMedS style) ---
            try
                [F, inliers] = estimateFundamentalMatrix( ...
                    matchedPoints1, matchedPoints2, ...
                    'NumTrials', 4000);
            catch
                uialert(app.UIFigure, ...
                    "Not enough matches to compute the Fundamental Matrix.", ...
                    "Error");
                return;
            end

            inlierPts1 = matchedPoints1(inliers,:);
            inlierPts2 = matchedPoints2(inliers,:);

            if size(inlierPts1,1) < 8
                uialert(app.UIFigure, ...
                    "Too few inliers after estimation. Increase Match Limit.", ...
                    "Error");
                return;
            end

            %% ================= LEFT IMAGE =================
            imshow(I1, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            title(app.UIAxes, 'Inliers and Epipolar Lines in First Image');

            % Plot inlier points (green circles)
            plot(app.UIAxes, inlierPts1(:,1), inlierPts1(:,2), 'go');

            % Compute epipolar lines in LEFT image
            epiLines1 = epipolarLine(F', inlierPts2);

            % Convert lines to border points
            borderPts1 = lineToBorderPoints(epiLines1, size(I1));

            % Draw lines exactly like MATLAB tutorial
            line(app.UIAxes, ...
                borderPts1(:,[1 3])', ...
                borderPts1(:,[2 4])');

            hold(app.UIAxes, 'off');

            %% ================= RIGHT IMAGE =================
            imshow(I2, 'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'on');
            title(app.UIAxes2, 'Inliers and Epipolar Lines in Second Image');

            % Plot inlier points
            plot(app.UIAxes2, inlierPts2(:,1), inlierPts2(:,2), 'go');

            % Compute epipolar lines in RIGHT image
            epiLines2 = epipolarLine(F, inlierPts1);
            borderPts2 = lineToBorderPoints(epiLines2, size(I2));

            % Draw lines
            line(app.UIAxes2, ...
                borderPts2(:,[1 3])', ...
                borderPts2(:,[2 4])');

            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: Compute3DButton
        function Compute3DButtonPushed(app, event)
            if isempty(app.LeftImage) || isempty(app.RightImage)
                uialert(app.UIFigure, "Please upload both stereo images first.", "Error");
                return;
            end

            I1 = app.LeftImage;
            I2 = app.RightImage;

            % Grayscale versions
            if size(I1,3)==3, I1g = rgb2gray(I1); else, I1g = I1; end
            if size(I2,3)==3, I2g = rgb2gray(I2); else, I2g = I2; end


            %% --- 1. Detect and match features (SURF) ---
            pts1 = detectSURFFeatures(I1g);
            pts2 = detectSURFFeatures(I2g);

            [f1, v1] = extractFeatures(I1g, pts1);
            [f2, v2] = extractFeatures(I2g, pts2);

            indexPairs = matchFeatures(f1, f2, 'Unique', true);

            matched1 = v1(indexPairs(:,1));
            matched2 = v2(indexPairs(:,2));

            % Apply match limit (at least 8 points)
            limit = max(app.MatchLimitSpinner.Value, 8);
            limit = min(limit, matched1.Count);

            matched1 = matched1(1:limit);
            matched2 = matched2(1:limit);


            %% --- 2. Build (approximate) camera intrinsics ---
            % You can replace this with real calibration values if you have them.
            imgSize = size(I1g);          % [rows, cols]
            f  = app.FocalLengthEditField.Value;  % focal length in pixels
            cx = imgSize(2)/2;            % principal point (image center)
            cy = imgSize(1)/2;

            focalLength    = [f f];
            principalPoint = [cx cy];
            imageSize      = imgSize(1:2);

            intrinsics = cameraIntrinsics(focalLength, principalPoint, imageSize);


            %% --- 3. Estimate Essential matrix & inliers ---
            % Essential matrix for a calibrated camera
            [E, inlierIdx, status] = estimateEssentialMatrix( ...
                matched1, matched2, intrinsics);

            if status ~= 0 || nnz(inlierIdx) < 8
                uialert(app.UIFigure, ...
                    "Could not reliably estimate the Essential matrix. Try increasing Match Limit or using more textured images.", ...
                    "SfM Error");
                return;
            end

            inlierPoints1 = matched1(inlierIdx);
            inlierPoints2 = matched2(inlierIdx);


            %% --- 4. Recover relative camera pose (R,t) ---
            % estrelpose returns a rigidtform3d with R and Translation
            relativePose = estrelpose(E, intrinsics, ...
                inlierPoints1, inlierPoints2);

            % Camera 1 at origin
            camMatrix1 = cameraMatrix(intrinsics, eye(3), [0 0 0]);

            % Camera 2 uses the relative pose
            camMatrix2 = cameraMatrix(intrinsics, relativePose);


            %% --- 5. Triangulate 3D points ---
            worldPoints = triangulate( ...
                inlierPoints1.Location, ...
                inlierPoints2.Location, ...
                camMatrix1, camMatrix2);

            % Optional: store for later use
            app.WorldPoints = worldPoints;


            %% --- 6. Visualize 3D structure in UIAxes3 ---
            cla(app.UIAxes3);
            scatter3(app.UIAxes3, ...
                worldPoints(:,1), worldPoints(:,2), worldPoints(:,3), ...
                10, worldPoints(:,3), 'filled');

            axis(app.UIAxes3, 'equal');
            grid(app.UIAxes3, 'on');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            zlabel(app.UIAxes3, 'Z');
            title(app.UIAxes3, '3D Reconstruction (Structure from Motion)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = '';
            app.ResetImageButton = uibutton(app.UIFigure, 'push');
            app.ResetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageButtonPushed, true);
            app.ResetImageButton.Text = 'Reset Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Task1Tab = uitab(app.TabGroup);
            app.Task1Tab.Title = 'Task 1';
            app.FrequencyDomainFilteringPanel = uipanel(app.Task1Tab);
            app.FrequencyDomainFilteringPanel.Title = 'Frequency Domain Filtering';
            app.FrequencyTypeLabel = uilabel(app.FrequencyDomainFilteringPanel);
            app.FrequencyTypeLabel.Text = {'Frequency'; 'Type'};
            app.FrequencyTypeDropDown = uidropdown(app.FrequencyDomainFilteringPanel);
            app.FrequencyTypeDropDown.Items = {'Low-pass', 'High-pass'};
            app.FrequencyTypeDropDown.Value = 'Low-pass';
            app.FrequencyMethodLabel = uilabel(app.FrequencyDomainFilteringPanel);
            app.FrequencyMethodLabel.Text = {'Frequency'; 'Method'};
            app.FrequencyMethodDropDown = uidropdown(app.FrequencyDomainFilteringPanel);
            app.FrequencyMethodDropDown.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.FrequencyMethodDropDown.Value = 'Ideal';
            app.ApplyFrequencyButton = uibutton(app.FrequencyDomainFilteringPanel, 'push');
            app.ApplyFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFrequencyButtonPushed, true);
            app.ApplyFrequencyButton.Text = 'Apply Frequency';
            app.SpatialDomainFilteringPanel = uipanel(app.Task1Tab);
            app.SpatialDomainFilteringPanel.Title = 'Spatial Domain Filtering';
            app.FilterTypeDropDownLabel = uilabel(app.SpatialDomainFilteringPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.SpatialDomainFilteringPanel);
            app.FilterTypeDropDown.Items = {'Smoothing', 'Sharpening'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Smoothing';
            app.MethodDropDownLabel = uilabel(app.SpatialDomainFilteringPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.SpatialDomainFilteringPanel);
            app.MethodDropDown.Items = {'Box', 'Weighted Average', 'Median'};
            app.MethodDropDown.Value = 'Box';
            app.ApplyFilterButton = uibutton(app.SpatialDomainFilteringPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.BrightnessSliderLabel = uilabel(app.Task1Tab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Task1Tab);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.HistogramEqualizationButton = uibutton(app.Task1Tab, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.ColorSpaceGroup = uibuttongroup(app.Task1Tab);
            app.ColorSpaceGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorSpaceGroupSelectionChanged, true);
            app.ColorSpaceGroup.Title = 'Button Group2';
            app.RGBButton = uiradiobutton(app.ColorSpaceGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.HSIButton = uiradiobutton(app.ColorSpaceGroup);
            app.HSIButton.Text = 'HSI';
            app.LabButton = uiradiobutton(app.ColorSpaceGroup);
            app.LabButton.Text = 'Lab';
            app.YCbCrButton = uiradiobutton(app.ColorSpaceGroup);
            app.YCbCrButton.Text = 'YCbCr';
            app.Task2Tab = uitab(app.TabGroup);
            app.Task2Tab.Title = 'Task 2';
            app.ImagePyramidPanel = uipanel(app.Task2Tab);
            app.ImagePyramidPanel.Title = 'Image Pyramid';
            app.ImagePyramidLevelSpinnerLabel = uilabel(app.ImagePyramidPanel);
            app.ImagePyramidLevelSpinnerLabel.Text = 'Image Pyramid Level';
            app.ImagePyramidLevelSpinner = uispinner(app.ImagePyramidPanel);
            app.ImagePyramidLevelSpinner.Limits = [1 3];
            app.ImagePyramidLevelSpinner.ValueDisplayFormat = '%.0f';
            app.ImagePyramidLevelSpinner.Value = 1;
            app.ImagePyramidTypeDropDownLabel = uilabel(app.ImagePyramidPanel);
            app.ImagePyramidTypeDropDownLabel.Text = 'Image Pyramid Type';
            app.ImagePyramidTypeDropDown = uidropdown(app.ImagePyramidPanel);
            app.ImagePyramidTypeDropDown.Items = {'reduce', 'expand'};
            app.ImagePyramidTypeDropDown.Value = 'reduce';
            app.ApplyPyramidButton = uibutton(app.ImagePyramidPanel, 'push');
            app.ApplyPyramidButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyPyramidButtonPushed, true);
            app.ApplyPyramidButton.Text = 'Apply Pyramid';
            app.TemplateMatchingPanel = uipanel(app.Task2Tab);
            app.TemplateMatchingPanel.Title = 'Template Matching';
            app.SelectTemplateButton = uibutton(app.TemplateMatchingPanel, 'push');
            app.SelectTemplateButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTemplateButtonPushed, true);
            app.SelectTemplateButton.Text = 'Select Template';
            app.RunMatchingButton = uibutton(app.TemplateMatchingPanel, 'push');
            app.RunMatchingButton.ButtonPushedFcn = createCallbackFcn(app, @RunMatchingButtonPushed, true);
            app.RunMatchingButton.Text = 'Run Matching';
            app.ShowCorrelationButton = uibutton(app.TemplateMatchingPanel, 'push');
            app.ShowCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCorrelationButtonPushed, true);
            app.ShowCorrelationButton.Text = 'Show Correlation';
            app.TabGroup2 = uitabgroup(app.Task2Tab);
            app.LMFilterBanksTab = uitab(app.TabGroup2);
            app.LMFilterBanksTab.Title = 'LM Filter Banks';
            app.Panel_2 = uipanel(app.LMFilterBanksTab);
            app.OrientationKnobLabel = uilabel(app.Panel_2);
            app.OrientationKnobLabel.Text = 'Orientation';
            app.OrientationKnob = uiknob(app.Panel_2, 'discrete');
            app.OrientationKnob.Items = {'0', '30', '60', '90', '120', '150'};
            app.OrientationKnob.Value = '0';
            app.GaussianDerivativeDropDownLabel = uilabel(app.Panel_2);
            app.GaussianDerivativeDropDownLabel.Text = 'Gaussian Derivative';
            app.GaussianDerivativeDropDown = uidropdown(app.Panel_2);
            app.GaussianDerivativeDropDown.Items = {'First Derivative', 'Second Derivative'};
            app.GaussianDerivativeDropDown.Value = 'First Derivative';
            app.ScaleSpinnerLabel = uilabel(app.Panel_2);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.Panel_2);
            app.ScaleSpinner.Limits = [1 3];
            app.ScaleSpinner.Value = 1;
            app.ApplyButton = uibutton(app.Panel_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ApplyLOGFilterButton = uibutton(app.LMFilterBanksTab, 'push');
            app.ApplyLOGFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLOGFilterButtonPushed, true);
            app.ApplyLOGFilterButton.Text = 'Apply LOG Filter';
            app.ApplyGaussianFilterButton = uibutton(app.LMFilterBanksTab, 'push');
            app.ApplyGaussianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyGaussianFilterButtonPushed, true);
            app.ApplyGaussianFilterButton.Text = 'Apply Gaussian Filter';
            app.LOGFiltersDropDownLabel = uilabel(app.LMFilterBanksTab);
            app.LOGFiltersDropDownLabel.Text = 'LOG Filters';
            app.LOGFiltersDropDown = uidropdown(app.LMFilterBanksTab);
            app.LOGFiltersDropDown.Items = {'1', '2', '3', '4', '5', '7', '9', '12'};
            app.LOGFiltersDropDown.Value = '1';
            app.GaussianFiltersDropDownLabel = uilabel(app.LMFilterBanksTab);
            app.GaussianFiltersDropDownLabel.Text = 'Gaussian Filters';
            app.GaussianFiltersDropDown = uidropdown(app.LMFilterBanksTab);
            app.GaussianFiltersDropDown.Items = {'1', '2', '4', '8'};
            app.GaussianFiltersDropDown.Value = '1';
            app.SchmidFilterBankTab = uitab(app.TabGroup2);
            app.SchmidFilterBankTab.Title = 'Schmid Filter Bank';
            app.SchmidPairsDropDownLabel = uilabel(app.SchmidFilterBankTab);
            app.SchmidPairsDropDownLabel.Text = 'Schmid Pairs';
            app.SchmidPairsDropDown = uidropdown(app.SchmidFilterBankTab);
            app.SchmidPairsDropDown.Items = {'(2,1)', '(4,1)', '(4,2)', '(6,1)', '(6,2)', '(6,3)', '(8,1)', '(8,2)', '(8,3)', '(10,1)', '(10,2)', '(10,3)', '(10,4)'};
            app.SchmidPairsDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.SchmidPairsDropDown.Value = '1';
            app.ApplyFilterButton_2 = uibutton(app.SchmidFilterBankTab, 'push');
            app.ApplyFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButton_2Pushed, true);
            app.ApplyFilterButton_2.Text = 'Apply Filter';
            app.EdgeDetectionPanel = uipanel(app.Task2Tab);
            app.EdgeDetectionPanel.Title = 'Edge Detection';
            app.MethodDropDown_2Label = uilabel(app.EdgeDetectionPanel);
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodDropDown_2 = uidropdown(app.EdgeDetectionPanel);
            app.MethodDropDown_2.Items = {'Sobel', 'Prewitt', 'Roberts', 'log', 'zerocross', 'Canny', 'approxcanny'};
            app.MethodDropDown_2.Value = 'Sobel';
            app.ThresholdSpinnerLabel = uilabel(app.EdgeDetectionPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.EdgeDetectionPanel);
            app.ThresholdSpinner.Limits = [0 1];
            app.DirectionDropDownLabel = uilabel(app.EdgeDetectionPanel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.EdgeDetectionPanel);
            app.DirectionDropDown.Items = {'horizontal', 'vertical', 'both'};
            app.DirectionDropDown.Value = 'horizontal';
            app.SigmaSliderLabel = uilabel(app.EdgeDetectionPanel);
            app.SigmaSliderLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.EdgeDetectionPanel);
            app.SigmaSlider.Limits = [0.5 3];
            app.SigmaSlider.MajorTicks = [0.5 1 1.5 2 2.5 3];
            app.SigmaSlider.MinorTicks = [0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.SigmaSlider.Step = 0.1;
            app.SigmaSlider.Value = 0.5;
            app.FindEdgesButton = uibutton(app.EdgeDetectionPanel, 'push');
            app.FindEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @FindEdgesButtonPushed, true);
            app.FindEdgesButton.Text = 'Find Edges';
            app.CornerDetectionTab = uitab(app.TabGroup);
            app.CornerDetectionTab.Title = 'Corner Detection';
            app.CornerDetectionPanel = uipanel(app.CornerDetectionTab);
            app.CornerDetectionPanel.Title = 'Corner Detection';
            app.QualitySliderLabel = uilabel(app.CornerDetectionPanel);
            app.QualitySliderLabel.Text = 'Quality';
            app.QualitySlider = uislider(app.CornerDetectionPanel);
            app.QualitySlider.Limits = [0 1];
            app.NoofCornersLabel = uilabel(app.CornerDetectionPanel);
            app.NoofCornersLabel.Text = {'Number of'; 'Corners'};
            app.NumberofCornersSpinner = uispinner(app.CornerDetectionPanel);
            app.NumberofCornersSpinner.Limits = [5 Inf];
            app.NumberofCornersSpinner.Value = 5;
            app.FilterSizeSpinnerLabel = uilabel(app.CornerDetectionPanel);
            app.FilterSizeSpinnerLabel.Text = 'Filter Size';
            app.FilterSizeSpinner = uispinner(app.CornerDetectionPanel);
            app.FilterSizeSpinner.Limits = [3 21];
            app.FilterSizeSpinner.Value = 3;
            app.FindCornersButton = uibutton(app.CornerDetectionPanel, 'push');
            app.FindCornersButton.ButtonPushedFcn = createCallbackFcn(app, @FindCornersButtonPushed, true);
            app.FindCornersButton.Text = 'Find Corners';
            app.Task3Tab = uitab(app.TabGroup);
            app.Task3Tab.Title = 'Task3';
            app.DoGLoGPanel = uipanel(app.Task3Tab);
            app.DoGLoGPanel.Title = 'DoG/LoG';
            app.SigmaFirstValueSpinnerLabel = uilabel(app.DoGLoGPanel);
            app.SigmaFirstValueSpinnerLabel.Text = {'Sigma First'; 'Value'};
            app.SigmaFirstValueSpinner = uispinner(app.DoGLoGPanel);
            app.SigmaFirstValueSpinner.Step = 0.1;
            app.SigmaFirstValueSpinner.Limits = [0.1 10];
            app.SigmaFirstValueSpinner.Value = 0.1;
            app.SigmaSecondValueSpinnerLabel = uilabel(app.DoGLoGPanel);
            app.SigmaSecondValueSpinnerLabel.Text = {'Sigma Second'; 'Value'};
            app.SigmaSecondValueSpinner = uispinner(app.DoGLoGPanel);
            app.SigmaSecondValueSpinner.Step = 0.1;
            app.SigmaSecondValueSpinner.Limits = [0.1 10];
            app.SigmaSecondValueSpinner.Value = 0.1;
            app.ApplyDOGButton = uibutton(app.DoGLoGPanel, 'state');
            app.ApplyDOGButton.ValueChangedFcn = createCallbackFcn(app, @ApplyDOGButtonValueChanged, true);
            app.ApplyDOGButton.Text = 'Apply DOG';
            app.SigmaLoGValueLabel = uilabel(app.DoGLoGPanel);
            app.SigmaLoGValueLabel.Text = {'Sigma LoG'; 'Value'};
            app.SigmaLoGValueSpinner = uispinner(app.DoGLoGPanel);
            app.SigmaLoGValueSpinner.Step = 0.1;
            app.SigmaLoGValueSpinner.Limits = [0.1 10];
            app.SigmaLoGValueSpinner.Value = 0.1;
            app.ApplyLoGButton = uibutton(app.DoGLoGPanel, 'push');
            app.ApplyLoGButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLoGButtonPushed, true);
            app.ApplyLoGButton.Text = 'Apply LoG';
            app.HOGPanel = uipanel(app.Task3Tab);
            app.HOGPanel.Title = 'HOG';
            app.CellSizeSpinnerLabel = uilabel(app.HOGPanel);
            app.CellSizeSpinnerLabel.Text = 'Cell Size';
            app.CellSizeSpinner = uispinner(app.HOGPanel);
            app.CellSizeSpinner.Step = 2;
            app.CellSizeSpinner.Limits = [4 32];
            app.CellSizeSpinner.Value = 8;
            app.BlockSizeSpinnerLabel = uilabel(app.HOGPanel);
            app.BlockSizeSpinnerLabel.Text = 'Block Size';
            app.BlockSizeSpinner = uispinner(app.HOGPanel);
            app.BlockSizeSpinner.Limits = [1 4];
            app.BlockSizeSpinner.Value = 2;
            app.NoofBinsSpinnerLabel = uilabel(app.HOGPanel);
            app.NoofBinsSpinnerLabel.Text = 'No. of Bins';
            app.NoofBinsSpinner = uispinner(app.HOGPanel);
            app.NoofBinsSpinner.Limits = [4 20];
            app.NoofBinsSpinner.Value = 9;
            app.SignedOrientationCheckBox = uicheckbox(app.HOGPanel);
            app.SignedOrientationCheckBox.Text = {'Signed'; 'Orientation'};
            app.BlockOverlapLabel = uilabel(app.HOGPanel);
            app.BlockOverlapLabel.Text = {'Block'; 'Overlap'};
            app.BlockOverlapSpinner = uispinner(app.HOGPanel);
            app.BlockOverlapSpinner.Limits = [0 3];
            app.BlockOverlapSpinner.Value = 1;
            app.ApplyHOGButton = uibutton(app.HOGPanel, 'push');
            app.ApplyHOGButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHOGButtonPushed, true);
            app.ApplyHOGButton.Text = 'Apply HOG';
            app.HoughTransformPanel = uipanel(app.Task3Tab);
            app.HoughTransformPanel.Title = 'Hough Transform';
            app.EdgeMethodDropDownLabel = uilabel(app.HoughTransformPanel);
            app.EdgeMethodDropDownLabel.Text = 'Edge Method';
            app.EdgeMethodDropDown = uidropdown(app.HoughTransformPanel);
            app.EdgeMethodDropDown.Items = {'Prewitt', 'Canny', 'Sobel'};
            app.EdgeMethodDropDown.Value = 'Prewitt';
            app.MaxLinePeaksSpinnerLabel = uilabel(app.HoughTransformPanel);
            app.MaxLinePeaksSpinnerLabel.Text = {'Max Line'; 'Peaks'};
            app.MaxLinePeaksSpinner = uispinner(app.HoughTransformPanel);
            app.MaxLinePeaksSpinner.Limits = [1 20];
            app.MaxLinePeaksSpinner.Value = 5;
            app.ApplyHoughLinesButton = uibutton(app.HoughTransformPanel, 'push');
            app.ApplyHoughLinesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHoughLinesButtonPushed, true);
            app.ApplyHoughLinesButton.Text = 'Apply Hough Lines';
            app.MinRadiusSpinnerLabel = uilabel(app.HoughTransformPanel);
            app.MinRadiusSpinnerLabel.Text = 'Min Radius';
            app.MinRadiusSpinner = uispinner(app.HoughTransformPanel);
            app.MinRadiusSpinner.Limits = [1 100];
            app.MinRadiusSpinner.Value = 10;
            app.MaxRadiusSpinnerLabel = uilabel(app.HoughTransformPanel);
            app.MaxRadiusSpinnerLabel.Text = 'Max Radius';
            app.MaxRadiusSpinner = uispinner(app.HoughTransformPanel);
            app.MaxRadiusSpinner.Limits = [5 200];
            app.MaxRadiusSpinner.Value = 50;
            app.SensitivitySpinnerLabel = uilabel(app.HoughTransformPanel);
            app.SensitivitySpinnerLabel.Text = 'Sensitivity';
            app.SensitivitySpinner = uispinner(app.HoughTransformPanel);
            app.SensitivitySpinner.Step = 0.1;
            app.SensitivitySpinner.Limits = [0 1];
            app.SensitivitySpinner.Value = 0.85;
            app.ApplyHoughCirclesButton = uibutton(app.HoughTransformPanel, 'push');
            app.ApplyHoughCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHoughCirclesButtonPushed, true);
            app.ApplyHoughCirclesButton.Text = 'Apply Hough Circles';
            app.RANSACPanel = uipanel(app.Task3Tab);
            app.RANSACPanel.Title = 'RANSAC';
            app.RANSACThresholdSpinnerLabel = uilabel(app.RANSACPanel);
            app.RANSACThresholdSpinnerLabel.Text = {'RANSAC'; 'Threshold'};
            app.RANSACThresholdSpinner = uispinner(app.RANSACPanel);
            app.RANSACThresholdSpinner.Step = 0.1;
            app.RANSACThresholdSpinner.Limits = [0.5 5];
            app.RANSACThresholdSpinner.Value = 2;
            app.RANSACIteratorSpinnerLabel = uilabel(app.RANSACPanel);
            app.RANSACIteratorSpinnerLabel.Text = {'RANSAC'; 'Iterator'};
            app.RANSACIteratorSpinner = uispinner(app.RANSACPanel);
            app.RANSACIteratorSpinner.Step = 10;
            app.RANSACIteratorSpinner.Limits = [10 5000];
            app.RANSACIteratorSpinner.Value = 500;
            app.ApplyRANSACLineButton = uibutton(app.RANSACPanel, 'push');
            app.ApplyRANSACLineButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyRANSACLineButtonPushed, true);
            app.ApplyRANSACLineButton.Text = 'Apply RANSAC Line';
            app.CircleRminSpinnerLabel = uilabel(app.RANSACPanel);
            app.CircleRminSpinnerLabel.Text = 'Circle Rmin';
            app.CircleRminSpinner = uispinner(app.RANSACPanel);
            app.CircleRminSpinner.Limits = [5 30];
            app.CircleRminSpinner.Value = 10;
            app.CircleRmaxSpinnerLabel = uilabel(app.RANSACPanel);
            app.CircleRmaxSpinnerLabel.Text = 'Circle Rmax';
            app.CircleRmaxSpinner = uispinner(app.RANSACPanel);
            app.CircleRmaxSpinner.Limits = [10 200];
            app.CircleRmaxSpinner.Value = 80;
            app.ApplyRANSACCircleButton = uibutton(app.RANSACPanel, 'push');
            app.ApplyRANSACCircleButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyRANSACCircleButtonPushed, true);
            app.ApplyRANSACCircleButton.Text = 'Apply RANSAC Circle';
            app.StereoVisionTab = uitab(app.TabGroup);
            app.StereoVisionTab.Title = 'Stereo Vision';
            app.UIAxes3 = uiaxes(app.StereoVisionTab);
            title(app.UIAxes3, '3D Reconstruction')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
            app.StereoVisionPanel = uipanel(app.StereoVisionTab);
            app.StereoVisionPanel.Title = 'Stereo Vision';
            app.UploadLeftImageButton = uibutton(app.StereoVisionPanel, 'push');
            app.UploadLeftImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadLeftImageButtonPushed, true);
            app.UploadLeftImageButton.Text = 'Upload Left Image';
            app.UploadRightImageButton = uibutton(app.StereoVisionPanel, 'push');
            app.UploadRightImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRightImageButtonPushed, true);
            app.UploadRightImageButton.Text = 'Upload Right Image';
            app.MatchLimitSpinnerLabel = uilabel(app.StereoVisionPanel);
            app.MatchLimitSpinnerLabel.Text = 'Match Limit';
            app.MatchLimitSpinner = uispinner(app.StereoVisionPanel);
            app.MatchLimitSpinner.Step = 10;
            app.MatchLimitSpinner.Limits = [10 500];
            app.MatchLimitSpinner.Value = 50;
            app.ShowEpipolarLinesButton = uibutton(app.StereoVisionPanel, 'push');
            app.ShowEpipolarLinesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowEpipolarLinesButtonPushed, true);
            app.ShowEpipolarLinesButton.Text = 'Show Epipolar Lines';
            app.FocalLengthEditFieldLabel = uilabel(app.StereoVisionPanel);
            app.FocalLengthEditFieldLabel.Text = 'Focal Length';
            app.FocalLengthEditField = uieditfield(app.StereoVisionPanel, 'numeric');
            app.FocalLengthEditField.Value = 800;
            app.Compute3DButton = uibutton(app.StereoVisionPanel, 'push');
            app.Compute3DButton.ButtonPushedFcn = createCallbackFcn(app, @Compute3DButtonPushed, true);
            app.Compute3DButton.Text = 'Compute 3D';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_task3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end